classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure         % UI Figure
        Button      matlab.ui.control.Button % Upload Audio
        Button2     matlab.ui.control.Button % Upload Image
        LabelSlider matlab.ui.control.Label  % WMS
        Slider      matlab.ui.control.Slider % [0 100]
        Button3     matlab.ui.control.Button % Play Original Audio
        Button4     matlab.ui.control.Button % Play Watermark Audio
        UIAxes      matlab.ui.control.UIAxes % Original Audio waveforms
        UIAxes2     matlab.ui.control.UIAxes % Watermarked audio waveforms
        UIAxes3     matlab.ui.control.UIAxes % Grayscale Image
        Button5     matlab.ui.control.Button % Apply Watermark
        UIAxes4     matlab.ui.control.UIAxes % Histogram
    end


    properties (Access = private)
    audioData % To store the original audio data
    fs % Sampling frequency of the audio
    imageData % To store the image data
    modifiedAudio % To store the modified, watermarked audio
    watermarkStrength = 1; % Watermark strength parameter (default value is 1)
    
 % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Set default watermark strength to 1 (you can change this value)
            app.watermarkStrength = 1; 
            
            % Update the Slider position to match the default value
            app.Slider.Value = app.watermarkStrength;
            
            % Optionally, display a label to indicate the default strength value
            app.LabelSlider.Text = ['WMS: ', num2str(app.watermarkStrength)];
            
            % You can also initialize the axes with empty data or placeholders
            cla(app.UIAxes);  % Clear original audio waveform axes
            cla(app.UIAxes2); % Clear watermarked audio waveform axes
            cla(app.UIAxes3); % Clear grayscale image axes
            cla(app.UIAxes4); % Clear histogram axes
            
            % Optional: You can add any introductory information to your app or set initial states here
            % For example, you can set a message or label in your app UI
       
        
        % Button callback for uploading audio file
        function Upload_Audio_Button_Callback(app)
            % Load the audio file
            [file, path] = uigetfile('*.wav', 'Select an Audio File');
            if isequal(file, 0)
                return;  % If no file is selected, exit the function
            end
            
            % Read the audio file
            [app.audioData, app.fs] = audioread(fullfile(path, file));
            
            % Plot the audio waveform
            plot(app.UIAxes, (1:length(app.audioData)) / app.fs, app.audioData);
            title(app.UIAxes, 'Original Audio Waveform');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
        end
        
        % Method to plot the grayscale graph
        function PlotGrayscaleGraph(app)
            if ~isempty(app.imageData)
                % Convert the image to grayscale
                grayImage = rgb2gray(app.imageData);
                
                % Plot the grayscale image data as a graph (line plot)
                plot(app.UIAxes3, grayImage(:));  % Flatten the image for plotting
                
                % Set title and labels for the graph
                title(app.UIAxes3, 'Grayscale Image Plot');
                xlabel(app.UIAxes3, 'Pixel Index');
                ylabel(app.UIAxes3, 'Grayscale Intensity');
            end
        end
        
        % Method to plot the histogram
        function PlotHistogram(app)
            if ~isempty(app.imageData)
                % Convert the image to grayscale
                grayImage = rgb2gray(app.imageData);
                
                % Compute the histogram using histcounts
                [counts, binLocations] = histcounts(grayImage, 256);  % 256 bins for grayscale image
                
                % Plot the histogram using the bar function
                bar(app.UIAxes4, binLocations(1:end-1), counts, 'BarWidth', 1);
                
                % Set title and labels for the histogram
                title(app.UIAxes4, 'Grayscale Image Histogram');
                xlabel(app.UIAxes4, 'Intensity');
                ylabel(app.UIAxes4, 'Frequency');
            end


        end

        % Button button pushed function
        function Upload_Audio_Button_Callback(app)
            [file, path] = uigetfile('*.wav', 'Select an Audio File');
            if isequal(file, 0)
                return;
            end
            [app.audioData, app.fs] = audioread(fullfile(path, file));
            plot(app.UIAxes, (1:length(app.audioData)) / app.fs, app.audioData);
            title(app.UIAxes, 'Original Audio Waveform');
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
        
        end

        % Button2 button pushed function
        function Upload_Image_Button_Callback(app)
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.gif', 'Image Files'});
            if file
                app.imageData = imread(fullfile(path, file));
                
                % Call the functions to plot the graphs
                PlotGrayscaleGraph(app);  % Plot grayscale graph
                PlotHistogram(app);       % Plot histogram
            end
                   
        end

        % Slider value changed function
        function watermar_Strength(app)
            app.watermarkStrength = app.Slider.Value;
            app.LabelSlider.Text = ['WMS: ', num2str(app.watermarkStrength)];
        end

        % Button5 button pushed function
        function Watermark_Button(app)
            if isempty(app.audioData) || isempty(app.imageData)
                uialert(app.UIFigure, 'Please upload both audio and image files.', 'Missing Input');
                return;
            end

            % Convert the grayscale image to binary data
            grayImage = rgb2gray(app.imageData);
            conv_binary = uint8(zeros(size(grayImage, 1), size(grayImage, 2) * 8));
            for i = 1:size(grayImage, 1)
                for j = 1:size(grayImage, 2)
                    char_array = uint8(dec2bin(grayImage(i, j), 8) - '0');
                    for k = 1:8
                        conv_binary(i, (j - 1) * 8 + k) = char_array(k);
                    end
                end
            end
            % Flatten binary data
            conv_binary_single = double(reshape(conv_binary, [], 1));
            
            % Embed watermark data into audio (simplified example)
            app.modifiedAudio = app.audioData; % Replace with actual watermarking logic if available
        
            % Plot modified audio
            plot(app.UIAxes2, (1:length(app.modifiedAudio)) / app.fs, app.modifiedAudio);
            title(app.UIAxes2, 'Watermarked Audio Waveform');
            xlabel(app.UIAxes2, 'Time (s)');
            ylabel(app.UIAxes2, 'Amplitude');
        end

        % Button3 button pushed function
        function play_original_audio(app)
            if ~isempty(app.audioData)
                sound(app.audioData, app.fs);
            else
                uialert(app.UIFigure, 'Please upload an audio file.', 'No Audio');
            end
   
        end

        % Button4 button pushed function
        function watermarkbuttonaudio(app)
             if ~isempty(app.modifiedAudio)
                sound(app.modifiedAudio, app.fs);
            else
                uialert(app.UIFigure, 'Please apply watermark first.', 'No Watermarked Audio');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Upload_Audio_Button_Callback);
            app.Button.Text = 'Upload Audio';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Upload_Image_Button_Callback);
            app.Button2.Text = 'Upload Image';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'WMS';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @watermar_Strength);
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @play_original_audio);
            app.Button3.Text = 'Play Original Audio';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @watermarkbuttonaudio);
            app.Button4.Text = 'Play Watermark Audio';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Audio waveforms');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Watermarked audio waveforms');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Grayscale Image');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Watermark_Button);
            app.Button5.Text = 'Apply Watermark';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end